classdef Lamp_design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LampDesignerUIFigure            matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        LoopsSliderLabel                matlab.ui.control.Label
        LoopsSlider                     matlab.ui.control.Slider
        InnerRadSliderLabel             matlab.ui.control.Label
        InnerRadSlider                  matlab.ui.control.Slider
        SQRTSINCheckBox                 matlab.ui.control.CheckBox
        RefreshButton                   matlab.ui.control.Button
        theta_ampSliderLabel            matlab.ui.control.Label
        theta_ampSlider                 matlab.ui.control.Slider
        LoopsEditFieldLabel             matlab.ui.control.Label
        LoopsEditField                  matlab.ui.control.NumericEditField
        HeightSliderLabel               matlab.ui.control.Label
        HeightSlider                    matlab.ui.control.Slider
        surface_ASliderLabel            matlab.ui.control.Label
        surface_ASlider                 matlab.ui.control.Slider
        surface_FSliderLabel            matlab.ui.control.Label
        surface_FSlider                 matlab.ui.control.Slider
        z_ampSliderLabel                matlab.ui.control.Label
        z_ampSlider                     matlab.ui.control.Slider
        z2_ampSliderLabel               matlab.ui.control.Label
        z2_ampSlider                    matlab.ui.control.Slider
        z_periodsSliderLabel            matlab.ui.control.Label
        z_periodsSlider                 matlab.ui.control.Slider
        zwave_offsetSliderLabel         matlab.ui.control.Label
        zwave_offsetSlider              matlab.ui.control.Slider
        zbulge_offsetSliderLabel        matlab.ui.control.Label
        zbulge_offsetSlider             matlab.ui.control.Slider
        Clickrefresh2xaftereachchangeLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        FunctionforFullcontrolEditFieldLabel  matlab.ui.control.Label
        FunctionforFullcontrolEditField  matlab.ui.control.EditField
        ChangemaxZtoEditFieldLabel      matlab.ui.control.Label
        ChangemaxZtoEditField           matlab.ui.control.EditField
        UIAxes2                         matlab.ui.control.UIAxes
        Changet_offsettoEditFieldLabel  matlab.ui.control.Label
        Changet_offsettoEditField       matlab.ui.control.EditField
        t_offsetSliderLabel             matlab.ui.control.Label
        t_offsetSlider                  matlab.ui.control.Slider
        AdjustslideruntilacreaseliesonxaxisLabel  matlab.ui.control.Label
        radiusofringholderEditFieldLabel  matlab.ui.control.Label
        radiusofringholderEditField     matlab.ui.control.EditField
        holder_ampSliderLabel           matlab.ui.control.Label
        holder_ampSlider                matlab.ui.control.Slider
        xfunEditFieldLabel              matlab.ui.control.Label
        xfunEditField                   matlab.ui.control.EditField
        yfunEditFieldLabel              matlab.ui.control.Label
        yfunEditField                   matlab.ui.control.EditField
        upScaleEditFieldLabel           matlab.ui.control.Label
        upScaleEditField                matlab.ui.control.EditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        loops
        
    end
    
    methods (Access = private)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.LoopsEditField.Value = app.LoopsSlider.Value
            t = 0:.05:2*pi;
            %R =  .7*sin(6.*t)+2; % %sin(6*t) + 2
            
            R = 0.5*(1 + sin(app.LoopsSlider.Value*t)).^.5;
            
            t = t(R>=0);
            R = R(R>=0);
            
            R = ones(length(t)).*R;
            
            Z = linspace(0,10, length(t));
            
            Z = ones(length(t)) .* Z;
            
            R = R + .5*sin(Z'/max(max(Z)).*pi) + .1*sin(Z'/max(max(Z)).*7.5*pi);
            
            X = R.*cos(t);
            Y = R.*sin(t)
            
            mesh(app.UIAxes,X,Y,Z') % UIAxes is the name of your axes object in app designer. Change it according to your app
            axis(app.UIAxes, 'tight');
            hold(app.UIAxes);
           
        end

        % Callback function: LoopsSlider, RefreshButton, 
        % SQRTSINCheckBox
        function LoopsSliderValueChanging(app, event)
            %changingValue = event.Value;
            app.LoopsEditField.Value = app.LoopsSlider.Value
            %t = 0:.05:2*pi ;
            t = linspace(0,2*pi,360)
            a = app.InnerRadSlider.Value
            if app.SQRTSINCheckBox.Value
                R = app.theta_ampSlider.Value * (1 + sin(app.LoopsSlider.Value*(t- app.t_offsetSlider.Value))).^.5 + a;
            else
                R =  app.theta_ampSlider.Value *sin(app.LoopsSlider.Value.*(t- app.t_offsetSlider.Value))+a; % %sin(6*t) + 2
            end
            
            R = R + app.surface_ASlider.Value*sin(app.surface_FSlider.Value*app.LoopsSlider.Value.*(t- app.t_offsetSlider.Value))
            
            t = t(R>=0);
            R = R(R>=0);
            
            R = ones(length(t)).*R;
            
            zmax = app.HeightSlider.Value;
            Z = linspace(0,zmax, length(t));
            
            Z = ones(length(t)) .* Z;
            
            R = R + app.z_ampSlider.Value*sin(Z'/max(max(Z)).* pi-app.zbulge_offsetSlider.Value) + app.z2_ampSlider.Value*sin(Z'/max(max(Z)).* app.z_periodsSlider.Value *pi -app.zwave_offsetSlider.Value);
            
            X = R.*cos(t);
            Y = R.*sin(t);
            
            mesh(app.UIAxes,X,Y,Z',ones(length(t))) % UIAxes is the name of your axes object in app designer. Change it according to your app
            axis(app.UIAxes, 'equal');
            hold(app.UIAxes);
            upscale = str2double(app.upScaleEditField.Value)
            x = upscale*X(1,:);
            y = upscale*Y(1,:);
            r = x.^2 + y.^2;
            xcrease = x(r==min(r));
            ycrease = y(r==min(r));
            
            %t_offset = atan2(ycrease(1),xcrease(1));
            
            hold(app.UIAxes2);
            plot(app.UIAxes2, x,y)
            %plot(app.UIAxes2, x2,y2)
   
            %find(min(r))
            xline(app.UIAxes2,0)
            yline(app.UIAxes2,0)
            
            t = linspace(0,1,64)
            ri = str2double(app.radiusofringholderEditField.Value)
            xHolder = ri + (x(1)-ri)*(1-t)
            FrameLineSpacingRatio = .2
            FrameWidthFactor = 2.5
            yHolder = (upscale*app.holder_ampSlider.Value*t).*(0.5-0.5.*cos((t.^0.66)*3*pi*2))
            yHolder = [yHolder;-yHolder]
            xRing = ri*cos(t*2*pi)
            yRing = ri*sin(t*2*pi)
            plot(app.UIAxes2, xHolder,yHolder, 'b')
            plot(app.UIAxes2, xRing,yRing, 'b')
            axis(app.UIAxes2, 'equal');
            
            drawnow
            drawnow
            
            if app.SQRTSINCheckBox.Value
                %R = app.theta_ampSlider.Value * (1 + sin(app.LoopsSlider.Value*t)).^.5 + a;
                app.FunctionforFullcontrolEditField.Value = ['= upScale*(' num2str(app.theta_ampSlider.Value) ...
                    '* (1 + SIN(' num2str(app.LoopsSlider.Value) '*(Tval-t_offset)*pi_2))^(0.5) + ' num2str(a) ...
                    ' + ' num2str(app.surface_ASlider.Value) '*SIN(' num2str(app.surface_FSlider.Value) '*' num2str(app.LoopsSlider.Value)...
                    '*(Tval-t_offset)*pi_2) + ' num2str(app.z_ampSlider.Value) ...
                    '*SIN((Zval)/maxZ/upScale*PI() -' num2str(app.zbulge_offsetSlider.Value) ...
                    ') + ' num2str(app.z2_ampSlider.Value) '*SIN((Zval)/maxZ/upScale*' num2str(app.z_periodsSlider.Value) ...
                    '*PI()-' num2str(app.zwave_offsetSlider.Value) '))']
                
                app.xfunEditField.Value = [' =xc + ' num2str(ri) '+0.5*LH + (( upScale*(' num2str(app.theta_ampSlider.Value) ...
                    '* (1 + SIN(' num2str(app.LoopsSlider.Value) '*(0-t_offset)*pi_2))^(0.5) + ' num2str(a) ...
                    ' + ' num2str(app.surface_ASlider.Value) '*SIN(' num2str(app.surface_FSlider.Value) '*' num2str(app.LoopsSlider.Value)...
                    '*(0-t_offset)*pi_2) + ' num2str(app.z_ampSlider.Value) ...
                    '*SIN((Zval)/maxZ/upScale*PI() -' num2str(app.zbulge_offsetSlider.Value) ...
                    ') + ' num2str(app.z2_ampSlider.Value) '*SIN((Zval)/maxZ/upScale*' num2str(app.z_periodsSlider.Value) ...
                    '*PI()-' num2str(app.zwave_offsetSlider.Value) '))-0.1- ' num2str(ri) ')*(1-Tval))']
            else
                %R =  app.theta_ampSlider.Value *sin(app.LoopsSlider.Value.*t)+a; % %sin(6*t) + 2
                
                app.FunctionforFullcontrolEditField.Value = ['= upScale*(' num2str(app.theta_ampSlider.Value) ...
                    '*SIN(' num2str(app.LoopsSlider.Value) '*(Tval-t_offset)*pi_2) + ' num2str(a) ...
                    ' + ' num2str(app.surface_ASlider.Value) '*SIN(' num2str(app.surface_FSlider.Value) '*' num2str(app.LoopsSlider.Value)...
                    '*(Tval-t_offset)*pi_2) + ' num2str(app.z_ampSlider.Value) ...
                    '*SIN((Zval)/maxZ/upScale*PI() -' num2str(app.zbulge_offsetSlider.Value) ') + ' num2str(app.z2_ampSlider.Value)...
                    '*SIN((Zval)/maxZ/upScale*' num2str(app.z_periodsSlider.Value) '*PI()-' num2str(app.zwave_offsetSlider.Value) '))']
                
                app.xfunEditField.Value = [' =xc + ' num2str(ri) '+0.5*LH + (( upScale*(' num2str(app.theta_ampSlider.Value) ...
                    '*SIN(' num2str(app.LoopsSlider.Value) '*(0-t_offset)*pi_2) + ' num2str(a) ...
                    ' + ' num2str(app.surface_ASlider.Value) '*SIN(' num2str(app.surface_FSlider.Value) '*' num2str(app.LoopsSlider.Value)...
                    '*(0-t_offset)*pi_2) + ' num2str(app.z_ampSlider.Value) ...
                    '*SIN((Zval)/maxZ/upScale*PI() -' num2str(app.zbulge_offsetSlider.Value) ') + ' num2str(app.z2_ampSlider.Value)...
                    '*SIN((Zval)/maxZ/upScale*' num2str(app.z_periodsSlider.Value) '*PI()-' num2str(app.zwave_offsetSlider.Value) '))-0.1- ' num2str(ri) ')*(1-Tval))']
            end
            
            app.yfunEditField.Value = ['= yc + (FrameLineSpacingRatio*(FrameWidthFactor*0.4))+(upScale*' num2str(app.holder_ampSlider.Value) '*Tval)*((0.5-0.5*COS((Tval^0.66)*3*pi_2))^1)']
            app.ChangemaxZtoEditField.Value = num2str(app.HeightSlider.Value)
            app.Changet_offsettoEditField.Value = num2str(app.t_offsetSlider.Value)


            % app.z_ampSlider.Value*sin(Z'/max(max(Z)).*pi) + app.z2_ampSlider.Value*sin(Z'/max(max(Z)).*7.5*pi);
        end

        % Value changed function: LoopsEditField
        function LoopsEditFieldValueChanged(app, event)
            value = app.LoopsEditField.Value;
            app.LoopsSlider.Value = value
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.LampDesignerUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {759, 759};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LampDesignerUIFigure = uifigure('Visible', 'off');
            app.LampDesignerUIFigure.Name = 'Lamp Designer';
            app.LampDesignerUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.LampDesignerUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoopsSliderLabel = uilabel(app.LeftPanel);
            app.LoopsSliderLabel.Text = 'Loops';
            app.LoopsSlider = uislider(app.LeftPanel);
            app.LoopsSlider.Limits = [0 8];
            app.LoopsSlider.MajorTicks = [0 2 4 6 8];
            app.LoopsSlider.ValueChangingFcn = createCallbackFcn(app, @LoopsSliderValueChanging, true);
            app.LoopsSlider.MinorTicks = [0 1 2 3 4 5 6 7 8];
            app.LoopsSlider.Value = 6;
            app.InnerRadSliderLabel = uilabel(app.LeftPanel);
            app.InnerRadSliderLabel.Text = 'Inner Rad';
            app.InnerRadSlider = uislider(app.LeftPanel);
            app.InnerRadSlider.Limits = [0 5];
            app.InnerRadSlider.Value = 1;
            app.SQRTSINCheckBox = uicheckbox(app.LeftPanel);
            app.SQRTSINCheckBox.ValueChangedFcn = createCallbackFcn(app, @LoopsSliderValueChanging, true);
            app.SQRTSINCheckBox.Text = 'SQRT SIN';
            app.SQRTSINCheckBox.Value = true;
            app.RefreshButton = uibutton(app.LeftPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @LoopsSliderValueChanging, true);
            app.RefreshButton.Text = 'Refresh';
            app.theta_ampSliderLabel = uilabel(app.LeftPanel);
            app.theta_ampSliderLabel.Text = 'theta_amp';
            app.theta_ampSlider = uislider(app.LeftPanel);
            app.theta_ampSlider.Limits = [0 2];
            app.theta_ampSlider.Value = 0.5;
            app.LoopsEditFieldLabel = uilabel(app.LeftPanel);
            app.LoopsEditFieldLabel.Text = 'Loops';
            app.LoopsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LoopsEditField.ValueChangedFcn = createCallbackFcn(app, @LoopsEditFieldValueChanged, true);
            app.HeightSliderLabel = uilabel(app.LeftPanel);
            app.HeightSliderLabel.Text = 'Height';
            app.HeightSlider = uislider(app.LeftPanel);
            app.HeightSlider.Limits = [0 20];
            app.HeightSlider.Value = 10;
            app.surface_ASliderLabel = uilabel(app.LeftPanel);
            app.surface_ASliderLabel.Text = 'surface_A';
            app.surface_ASlider = uislider(app.LeftPanel);
            app.surface_ASlider.Limits = [0 0.1];
            app.surface_ASlider.Value = 0.01;
            app.surface_FSliderLabel = uilabel(app.LeftPanel);
            app.surface_FSliderLabel.Text = 'surface_F';
            app.surface_FSlider = uislider(app.LeftPanel);
            app.surface_FSlider.Limits = [0 1000];
            app.surface_FSlider.Value = 100;
            app.z_ampSliderLabel = uilabel(app.LeftPanel);
            app.z_ampSliderLabel.Text = 'z_amp';
            app.z_ampSlider = uislider(app.LeftPanel);
            app.z_ampSlider.Limits = [0 2];
            app.z_ampSlider.Value = 0.49;
            app.z2_ampSliderLabel = uilabel(app.LeftPanel);
            app.z2_ampSliderLabel.Text = 'z2_amp';
            app.z2_ampSlider = uislider(app.LeftPanel);
            app.z2_ampSlider.Limits = [0 0.5];
            app.z2_ampSlider.Value = 0.1;
            app.z_periodsSliderLabel = uilabel(app.LeftPanel);
            app.z_periodsSliderLabel.Text = 'z_periods';
            app.z_periodsSlider = uislider(app.LeftPanel);
            app.z_periodsSlider.Limits = [0 15];
            app.z_periodsSlider.Value = 7.5;
            app.zwave_offsetSliderLabel = uilabel(app.LeftPanel);
            app.zwave_offsetSliderLabel.Text = 'zwave_offset';
            app.zwave_offsetSlider = uislider(app.LeftPanel);
            app.zwave_offsetSlider.Limits = [0 6.28];
            app.zbulge_offsetSliderLabel = uilabel(app.LeftPanel);
            app.zbulge_offsetSliderLabel.Text = 'zbulge_offset';
            app.zbulge_offsetSlider = uislider(app.LeftPanel);
            app.zbulge_offsetSlider.Limits = [0 6.28];
            app.Clickrefresh2xaftereachchangeLabel = uilabel(app.LeftPanel);
            app.Clickrefresh2xaftereachchangeLabel.Text = 'Click refresh 2x after each change';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Lamp Shade Preview')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.FunctionforFullcontrolEditFieldLabel = uilabel(app.RightPanel);
            app.FunctionforFullcontrolEditFieldLabel.Text = 'Function for Full control';
            app.FunctionforFullcontrolEditField = uieditfield(app.RightPanel, 'text');
            app.ChangemaxZtoEditFieldLabel = uilabel(app.RightPanel);
            app.ChangemaxZtoEditFieldLabel.Text = 'Change maxZ to';
            app.ChangemaxZtoEditField = uieditfield(app.RightPanel, 'text');
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Lamp Holder Design tuning')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.Changet_offsettoEditFieldLabel = uilabel(app.RightPanel);
            app.Changet_offsettoEditFieldLabel.Text = 'Change t_offset to';
            app.Changet_offsettoEditField = uieditfield(app.RightPanel, 'text');
            app.t_offsetSliderLabel = uilabel(app.RightPanel);
            app.t_offsetSliderLabel.Text = 't_offset';
            app.t_offsetSlider = uislider(app.RightPanel);
            app.t_offsetSlider.Limits = [0 3.14];
            app.AdjustslideruntilacreaseliesonxaxisLabel = uilabel(app.RightPanel);
            app.AdjustslideruntilacreaseliesonxaxisLabel.Text = 'Adjust slider until a crease lies on x axis';
            app.radiusofringholderEditFieldLabel = uilabel(app.RightPanel);
            app.radiusofringholderEditFieldLabel.Text = 'radius of ring holder';
            app.radiusofringholderEditField = uieditfield(app.RightPanel, 'text');
            app.radiusofringholderEditField.Value = '1';
            app.holder_ampSliderLabel = uilabel(app.RightPanel);
            app.holder_ampSliderLabel.Text = 'holder_amp';
            app.holder_ampSlider = uislider(app.RightPanel);
            app.holder_ampSlider.Limits = [0 10];
            app.holder_ampSlider.Value = 0.5;
            app.xfunEditFieldLabel = uilabel(app.RightPanel);
            app.xfunEditFieldLabel.Text = 'x-fun';
            app.xfunEditField = uieditfield(app.RightPanel, 'text');
            app.yfunEditFieldLabel = uilabel(app.RightPanel);
            app.yfunEditFieldLabel.Text = 'y-fun';
            app.yfunEditField = uieditfield(app.RightPanel, 'text');
            app.upScaleEditFieldLabel = uilabel(app.RightPanel);
            app.upScaleEditFieldLabel.Text = 'upScale';
            app.upScaleEditField = uieditfield(app.RightPanel, 'text');
            app.upScaleEditField.Value = '1';
            % Show the figure after all components are created
            app.LampDesignerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lamp_design

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LampDesignerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LampDesignerUIFigure)
        end
    end
end